classdef MazeSimulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Button_5     matlab.ui.control.Button
        Button_4     matlab.ui.control.Button
        Button_3     matlab.ui.control.Button
        Button_2     matlab.ui.control.Button
        Button       matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
        ContextMenu  matlab.ui.container.ContextMenu
        Menu         matlab.ui.container.Menu
        Menu2        matlab.ui.container.Menu
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %createROI(app);
            %Import Maze from Image
            
             [maze_size, walldata]= mazeloader();
             app.UIAxes.XLim = [0 maze_size];
             app.UIAxes.YLim = [0 maze_size];
             convertmazedata(maze_size,walldata);
             DrawMaze(app.UIAxes);
             pbaspect([1 1 1]);
             %app.UIAxes.UserData = maze_size;
             leftsearch(app.UIAxes,maze_size);
             %h = viscircles(app.UIAxes,[x y],0.3);
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Maze')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 32];
            app.UIAxes.YLim = [0 32];
            app.UIAxes.XTick = [0 4 8 12 16 20 24 28 32];
            app.UIAxes.XTickLabel = {'0'; '4'; '8'; '12'; '16'; '20'; '24'; '28'; '32'};
            app.UIAxes.YTick = [0 4 8 12 16 20 24 28 32];
            app.UIAxes.YTickLabel = {'0'; '4'; '8'; '12'; '16'; '20'; '24'; '28'; '32'};
            app.UIAxes.ZTickLabel = '';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '迷路のインポート';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Text = '迷路の保存';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '開始';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.Text = '停止';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.Text = 'リセット';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MazeSimulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end